classdef App_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        OpenMenu     matlab.ui.container.Menu
        QuitMenu     matlab.ui.container.Menu
        GridLayout   matlab.ui.container.GridLayout
        TabGroup     matlab.ui.container.TabGroup
        SignalTab    matlab.ui.container.Tab
        STFTTab      matlab.ui.container.Tab
        CWTTab       matlab.ui.container.Tab
        MetadataTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Header
        Record
        Fileinfo
        Filepath
        Regions
    end
    
    methods (Access = public)
        
        function [signal, fs, label] = getSelectedSignal(app)
            signal = [];
            fs = [];
            label = '';
        
            % validate EDF file
            if isempty(app.record)
                uialert(f, 'Load an EDF file first', 'Signal Error')
                return
            end
        
            % validate channel
            region = app.regions.(ddRegion.Value);
            label = ddChannel.Value;
        
            if strcmp(label, 'Total')
                uialert(f, 'Select a single channel first', 'Signal Error')
                return
            end
        
            realName = region.(label);
            idx = find(strcmp(app.hdr.label,realName));
        
            if isempty(idx)
                uialert(f, 'Channel not found in EDF', 'Signal Error')
                return
            end
        
            % convert signal to physical units
            signal = convertToPhysical(idx);
        
            signal = signal(~isnan(signal));
            signal = signal(~isinf(signal));
        
            % sampling rate
            fs = app.fileinfo.NumSamples(1) / seconds(app.fileinfo.DataRecordDuration);
        
            % time interval selection
            tStart = edtStart.Value;
            tEnd   = edtEnd.Value;
        
            iStart = max(1, round(tStart * fs));
            iEnd   = min(length(signal), round(tEnd * fs));
        
            if iEnd <= iStart
                uialert(f, 'Invalid time interval', 'Signal Error')
                signal = [];
                return
            end
        
            signal = signal(iStart:iEnd);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Regions.Frontal = struct( ...
                'Fp1','EEGFp1LE','Fp2','EEGFp2LE','F3','EEGF3LE', ...
                'F4','EEGF4LE','F7','EEGF7LE','F8','EEGF8LE','Fz','EEGFzLE');
            app.Regions.Temporal = struct( ...
                'T3','EEGT3LE','T4','EEGT4LE','T5','EEGT5LE','T6','EEGT6LE');
            app.Regions.Parietal = struct( ...
                'P3','EEGP3LE','P4','EEGP4LE','Pz','EEGPzLE');
            app.Regions.Occipital = struct( ...
                'O1','EEGO1LE','O2','EEGO2LE');
            app.Regions.Central = struct( ...
                'C3','EEGC3LE','C4','EEGC4LE','Cz','EEGCzLE');

            app.Header = [];
            app.Record = [];
            app.Fileinfo = [];
            app.Filepath = [];
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname] = uigetfile('*.edf','Select an EEG file');
            if isequal(filename, 0)
                return
            end
            
            filepath = fullfile(pathname, filename);
            [hdr,record] = edfread(filepath);
            fileinfo = edfinfo(filepath);
    
            app.Header = hdr;
            app.Record = record;
            app.Fileinfo = fileinfo;
            app.Filepath = filepath;
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.STFTTab = uitab(app.TabGroup);
            app.STFTTab.Title = 'STFT';
            app.CWTTab = uitab(app.TabGroup);
            app.CWTTab.Title = 'CWT';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end